classdef Crazy8image_latest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Player3Label                   matlab.ui.control.Label
        Player2Label                   matlab.ui.control.Label
        Player1Label                   matlab.ui.control.Label
        Player3                        matlab.ui.control.Button
        Player2                        matlab.ui.control.Button
        Player1                        matlab.ui.control.Button
        Image21                        matlab.ui.control.Image
        Image20                        matlab.ui.control.Image
        Image19                        matlab.ui.control.Image
        Image18                        matlab.ui.control.Image
        Image17                        matlab.ui.control.Image
        Image16                        matlab.ui.control.Image
        Image15                        matlab.ui.control.Image
        Image14                        matlab.ui.control.Image
        Image13                        matlab.ui.control.Image
        Image12                        matlab.ui.control.Image
        Image11                        matlab.ui.control.Image
        Image10                        matlab.ui.control.Image
        Image9                         matlab.ui.control.Image
        Image8                         matlab.ui.control.Image
        Image7                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image1                         matlab.ui.control.Image
        CheckWinButton                 matlab.ui.control.Button
        DiamondButton                  matlab.ui.control.Button
        HeartButton                    matlab.ui.control.Button
        SpadeButton                    matlab.ui.control.Button
        ClubButton                     matlab.ui.control.Button
        SuitSelectLabel                matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        HintsLabel                     matlab.ui.control.Label
        CurrentCardLabel               matlab.ui.control.Label
        DrawButton                     matlab.ui.control.Button
        Spot10Label                    matlab.ui.control.Label
        Spot1Label                     matlab.ui.control.Label
        NumberofPlayersEditField       matlab.ui.control.NumericEditField
        NumberofPlayersEditFieldLabel  matlab.ui.control.Label
        StartGameButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        deck
        stack
        up
        compatmx
        suitperm
        currentcompat
        startGame

        % Players Hands
        hand1 = zeros(1,52);
        hand1Count = 0;
        hand2 = zeros(1,52);
        hand2Count = 0;
        hand3 = zeros(1,52);
        hand3Count = 0;
        playerHand = zeros(1,52); % IMPORTANT: assigned hand to players machine
        
        % Hands DB Info
        userAPIKey = 'PK7DNGO6RBX0X4JL'; 
        channelIDHands = 2365854;
        writeKeyHands = 'COLYND9GR49DBSG6';
        readKeyHands = '2I53B4GJQX3ONNWB';

        % PlayerCount DB Info
        channelIDPlayerCount = 2371109;
        writeKeyPlayerCount = 'T4MGCTV4LM9FTB6R';
        readKeyPlayerCount = 'K4VPUZ1A6T4DEV1A';
        playerCount
        maxPlayers
        playerNum
    end
    
    methods (Access = private)
        
      function [] = changeTurns(app)
          % Change Turns
          if(app.playerNum == app.maxPlayers)
              
              % Make sure that the last player rotates to first
              app.playerCount = [ 1 0 0 ];
              disp(app.playerCount)
              app.writePlayerCount();
          else

              % Just increment the playerCount
              app.playerCount(app.playerNum+1) = 1;
              app.writePlayerCount();
          end
      end

      function [] = disphand(app)    
        for i = 1:21 %ill have this go to 52 once i add all 52 buttons.
            if app.playerHand(i) ~= 0
               
                cardname = name(app, i); % Get the name of the card at position i
                imageFile = [cardname,'.png']; 
                set(app.(sprintf("Image%d", i)),'ImageSource', convertCharsToStrings(imageFile));
          
                cardnamestack = stackname(app); %this shows the card in the stack
                imageFileStack = [cardnamestack,'.png'];
                app.Image1.ImageSource = convertCharsToStrings(imageFileStack);
            else 

               set(app.(sprintf("Image%d", i)),'ImageSource', convertCharsToStrings('Empty.png'));
            
            end
        end
      end


        function compat = compat(app, num)
            % Checks if played card is a valid play
            card = app.playerHand(num);
            [upr, upc] = find(app.compatmx==app.stack);
            [cardr, cardc] = find(app.compatmx==card);
            if (((cardr == upr) | (cardc == upc)) | ismember(card, app.compatmx(:,8)))
                % Updates Cards
                compat = true; 
                app.stack = card;
                for i = num:51
                    app.playerHand(i) = app.playerHand(i+1);
                end
                app.playerHand(52) = 0;
    
                app.changeTurns();
             else
                 compat = false; 
             end
            
            % If eight is played
            if ismember(card, app.compatmx(:,8))
                 app.suitperm = true; 
                 app.Label2.Text = "Crazy 8! Select a Suit!";
            end
            
            app.saveHands();
            disphand(app);
        end


      function cardname = stackname(app)
            cardnum = app.stack; 
            [cardr, cardc] = find(app.compatmx==cardnum);
            switch cardr
                case 1
                    suf = 'c'; 
                case 2
                    suf = 's';
                case 3
                    suf = 'h'; 
                case 4
                    suf = 'd';
            end
            switch cardc
                case 1
                pre = '1';
                case 2
                    pre = '2';
                case 3
                    pre = '3';
                case 4
                    pre = '4';
                case 5
                    pre = '5';
                    case 6
                    pre = '6';
                    case 7
                    pre = '7';
                    case 8
                    pre = '8';
                    case 9
                    pre = '9';
                    case 10
                    pre = '10';
                    case 11
                    pre = '11';
                    case 12
                    pre = '12';
                    case 13
                    pre = '13';
               
            end
            cardname = [suf,pre];


        end

function cardname = name(app, num)
            cardnum = app.playerHand(num); 
            if cardnum==0
                cardname = 'Empty'
                return
            end
           

            [cardr, cardc] = find(app.compatmx==cardnum);
            switch cardr
                case 1
                    suf = 'c'; 
                case 2
                    suf = 's';
                case 3
                    suf = 'h'; 
                case 4
                    suf = 'd';

            end
            switch cardc
                case 1
                pre = '1';
                case 2
                    pre = '2';
                case 3
                    pre = '3';
                case 4
                    pre = '4';
                case 5
                    pre = '5';
                    case 6
                    pre = '6';
                    case 7
                    pre = '7';
                    case 8
                    pre = '8';
                    case 9
                    pre = '9';
                    case 10
                    pre = '10';
                    case 11
                    pre = '11';
                    case 12
                    pre = '12';
                    case 13
                    pre = '13';
            end
            cardname = [suf,pre];
               
            
    end

    function [] = saveHands(app)
            % Save player's hands to DB
            hands = [];
            for i=1:app.maxPlayers
                hands{end+1} = mat2str(app.(sprintf("hand%d", i)));
            end

            % Set player's hand saved on machine to corresponding hand number
            hands{app.playerNum} = mat2str(app.playerHand);
            writeHandsToDB( ...
                hands, ...
                app.maxPlayers, ...
                app.channelIDHands, ...
                app.writeKeyHands ...
                );
    end

    function [] = readHand(app, handNumber)
        % Read From DB
        data = readHandsFromDB(app.maxPlayers, app.channelIDHands, app.readKeyHands);
        hand = data{handNumber};
        for i=1:app.maxPlayers
            app.(sprintf("hand%d", i)) = data{i}; % Save to handNumber's hand
            app.(sprintf("hand%dCount", i)) = sum(data{i}>0); % Save to handNumber's hand
        end

        % Make player's hand saved on machine to desired hand
        app.playerHand = hand;
    end

    function [] = writePlayerCount(app)
        i = 1;
        while i == 1
            try    
                % Write to data base
                pause(1);
                thingSpeakWrite( ...
                    app.channelIDPlayerCount, ...
                    'WriteKey', app.writeKeyPlayerCount, ...
                    'Fields', 1, ...
                    'Values', string(mat2str(app.playerCount)) ...
                    );               
                i = 0;   
            catch err
                disp(err);
                continue
            end
        end
    end

    function [] = readPlayerCount(app)
        i = 1;
        while i == 1
            try     
                % Read player count
                pause(1);
                data = thingSpeakRead( ...
                    app.channelIDPlayerCount, ...
                    'ReadKey', app.readKeyPlayerCount, ...
                    'Fields', 1, ...
                    'OutputFormat', 'table' ...
                    );
        
                % Format
                app.playerCount = data{1, "FieldLabel1"};
                app.playerCount = formatHand(app.playerCount{1}); % Returns vector
        
                i = i + 1;   
            catch err
                disp(err);
                continue
            end
        end
    end

    function [] = changeDisplayOfComponenets(app, toggleString)
        % toggleString can either be "on" or "off"
        % function sets all components visibility
        for i=1:21
            set(app.(sprintf("Image%d", i)),'Visible', toggleString);
        end
        app.CheckWinButton.Visible = toggleString;  
        app.DiamondButton.Visible = toggleString;
        app.HeartButton.Visible = toggleString;
        app.SpadeButton.Visible = toggleString;
        app.ClubButton.Visible = toggleString;
        app.SuitSelectLabel.Visible = toggleString;
        app.Label2.Visible = toggleString;
        app.HintsLabel.Visible = toggleString;
        app.CurrentCardLabel.Visible = toggleString;
        app.DrawButton.Visible = toggleString;
        app.Spot10Label.Visible = toggleString;
        app.Spot1Label.Visible = toggleString;

        if(toggleString == "off") 
            for i=1:3
                set(app.(sprintf("Player%d", i)), 'Visible', "on")
                set(app.(sprintf("Player%dLabel", i)), 'Visible', "on")
            end
        else
            for i=1:3
                set(app.(sprintf("Player%d", i)), 'Visible', "off")
                set(app.(sprintf("Player%dLabel", i)), 'Visible', "off")
            end
        end
    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set player count to all zeros
            app.playerCount = zeros(1,3);
            app.writePlayerCount();
            app.changeDisplayOfComponenets("off");

            % Game Loop
            i = 1;
            while i < 5
                % Begin iteration by reading playerCount
                pause(1);
                app.readPlayerCount(); % Keeps tracks whos turn it is
                currentPlayers = app.playerCount(app.playerCount == 1);

                % Check if game has been started on machine 
                if (app.startGame == 1)
                    disp("Game Started")
                    pause(1);
                    disp("IS PLAYING " + sum(app.playerCount));

                    % Check to see who current player is
                    if(sum(app.playerCount) == app.playerNum)
                        app.readHand(app.playerNum);
                        disp(app.playerHand);
                        app.changeDisplayOfComponenets("on");
                    else
                        
                        % For right now set display to none
                        app.changeDisplayOfComponenets("off");
                    end
                else
                    
                    % Before game has started see how many players have joined
                    for j = 1:length(currentPlayers)
                        disp(app.playerNum);
                        app.maxPlayers = sum(app.playerCount);
                        set(app.(sprintf("Player%dLabel", j)), "Text", "Ready");
                    end
                end
            end

        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
          % Let player 1 go first
          app.playerCount = [ 1 0 0 ];
          app.writePlayerCount();

          % Turn StartGameButton Off
          app.StartGameButton.Visible = "off";
          app.startGame = 1;

          app.compatmx = [1:13; 14:26; 27:39; 40:52]; 
          app.deck = randperm(52,52);
          app.hand1 = zeros(1,52);
          app.suitperm = false; 
          app.Label2.Text = " ";
          
               if app.maxPlayers == 1
                   app.hand1Count = 5;
                   for i=1:6
                        app.hand1(i) = app.deck(i);                       
                   end  
                        for i=1:6
                           app.deck = app.deck(app.deck~=app.hand1(i))
                        end
                   app.up = app.deck(1); %the facing up card is that which was just drawn by the dealer from the top of the deck
                   app.deck(1) = []; %the up card is no longer in the deck
                   app.stack = app.up; %the stack of played cards begins with the up card
        
                   disphand(app) %disp user's hand

               elseif app.maxPlayers == 2
                   
                    app.hand1(1:5) = app.deck(1:5) ;
                    app.hand2(1:5) = app.deck(6:10);           
                    app.hand1(1:5) = sort(app.hand1(1:5));
                    app.hand2(1:5) = sort(app.hand2(1:5));
                    app.hand1Count = 5;
                    app.hand2Count = 5;
                    
               elseif app.maxPlayers == 3
                   
                    app.hand1(1:5) = app.deck(1:5) ;
                    app.hand2(1:5) = app.deck(6:10);
                    app.hand3(1:5) = app.deck(11:15);
                    app.hand1(1:5) = sort(app.hand1(1:5));
                    app.hand2(1:5) = sort(app.hand2(1:5));
                    app.hand3(1:5) = sort(app.hand3(1:5));
                    app.hand1Count = 5;
                    app.hand2Count = 5;
                    app.hand3Count = 5;
                else 
                    app.hand1 = [] ;
                end
                app.playerHand = app.(sprintf("hand%d", app.playerNum));
                app.saveHands(); % Saves to DB


        end

        % Callback function
        function ButtonaPushed(app, event)
              num = 1; 
              compat(app, num);
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
           
app.playerHand(find((app.playerHand == 0),1)) = app.deck(1) %playerHand, at the location of its first zero, equals the top card in the deck
 %add the top of the deck to the hand
            app.deck(1) = [] %the top of the deck is no longer in the deck
            disp(app.playerHand) %for diag purposes
disp(app.deck) %for diag purposes
disphand(app) %display the user's hand
app.saveHands();
        end

        % Button pushed function: ClubButton
        function ClubButtonPushed(app, event)
            switch app.suitperm
                case true 
                app.stack = 8;
                app.suitperm = false;
                disphand(app);
                app.changeTurns();
                otherwise  
                    app.Label2.Text = "You know what you did, cheater";
            end
        end

        % Button pushed function: SpadeButton
        function SpadeButtonPushed(app, event)
               switch app.suitperm
                   case true 
                app.stack = 21;
                app.suitperm = false;
                disphand(app);
                app.changeTurns();
                   otherwise  
                    app.Label2.Text = "You know what you did, cheater";
            end
        end

        % Button pushed function: HeartButton
        function HeartButtonPushed(app, event)
               switch app.suitperm
                   case true 
                app.stack = 34;
                app.suitperm = false;
                disphand(app);
                app.changeTurns();
                   otherwise  
                    app.Label2.Text = "You know what you did, cheater";
            end
        end

        % Button pushed function: DiamondButton
        function DiamondButtonPushed(app, event)
               switch app.suitperm
                   case true
                app.stack = 47;
                app.suitperm = false;
                disphand(app);
                app.changeTurns();
                   otherwise 
                    app.Label2.Text = "You know what you did, cheater";
            end
        end

        % Image clicked function: Image2
        function Button_2Pushed(app, event)
            num = 2; 
              compat(app, num);
        end

        % Image clicked function: Image3
        function Button_3Pushed(app, event)
            num = 3; 
              compat(app, num);
        end

        % Image clicked function: Image4
        function Button_4Pushed(app, event)
            num = 4; 
              compat(app, num);
        end

        % Image clicked function: Image5
        function Button_5Pushed(app, event)
            num = 5; 
              compat(app, num);
        end

        % Image clicked function: Image6
        function Button_6Pushed(app, event)
            num = 6; 
              compat(app, num);
        end

        % Image clicked function: Image7
        function Button_7Pushed(app, event)
            num = 7; 
              compat(app, num);
        end

        % Image clicked function: Image8
        function Button_8Pushed(app, event)
            num = 8; 
              compat(app, num);
        end

        % Image clicked function: Image9
        function Button_9Pushed(app, event)
            num = 9; 
              compat(app, num);
        end

        % Image clicked function: Image10
        function Button_10Pushed(app, event)
            num = 10; 
              compat(app, num);
        end

        % Image clicked function: Image11
        function Button_11Pushed(app, event)
            num = 11; 
              compat(app, num);
        end

        % Image clicked function: Image12
        function Button_12Pushed(app, event)
            num = 12; 
              compat(app, num);
        end

        % Image clicked function: Image13
        function Button_13Pushed(app, event)
            num = 13; 
              compat(app, num);
        end

        % Image clicked function: Image14
        function Button_14Pushed(app, event)
            num = 14; 
              compat(app, num);
        end

        % Image clicked function: Image15
        function Button_15Pushed(app, event)
            num = 15; 
              compat(app, num);
        end

        % Image clicked function: Image16
        function Button_16Pushed(app, event)
            num = 16; 
              compat(app, num);
        end

        % Image clicked function: Image17
        function Button_17Pushed(app, event)
            num = 17; 
              compat(app, num);
        end

        % Image clicked function: Image18
        function Button_18Pushed(app, event)
            num = 18; 
              compat(app, num);
        end

        % Image clicked function: Image19
        function Button_19Pushed(app, event)
            num = 19; 
              compat(app, num);
        end

        % Image clicked function: Image20
        function Button_20Pushed(app, event)
            num = 20; 
              compat(app, num);
        end

        % Image clicked function: Image21
        function Button_21Pushed(app, event)
            num = 21; 
              compat(app, num);
        end

        % Callback function
        function Button_22Pushed(app, event)
            num = 22; 
              compat(app, num);
        end

        % Callback function
        function Button_23Pushed(app, event)
            num = 23; 
              compat(app, num);
        end

        % Callback function
        function Button_24Pushed(app, event)
            num = 24; 
              compat(app, num);
        end

        % Callback function
        function Button_25Pushed(app, event)
            num = 25; 
              compat(app, num);
        end

        % Callback function
        function Button_26Pushed(app, event)
            num = 26; 
              compat(app, num);
        end

        % Callback function
        function Button_27Pushed(app, event)
            num = 27; 
              compat(app, num);
        end

        % Callback function
        function Button_28Pushed(app, event)
            num = 28; 
              compat(app, num);
        end

        % Callback function
        function Button_29Pushed(app, event)
            num = 29; 
              compat(app, num);
        end

        % Callback function
        function Button_30Pushed(app, event)
            num = 30; 
              compat(app, num);
        end

        % Callback function
        function Button_31Pushed(app, event)
            num = 31; 
              compat(app, num);
        end

        % Callback function
        function Button_32Pushed(app, event)
            num = 32; 
              compat(app, num);
        end

        % Callback function
        function Button_33Pushed(app, event)
            num = 33; 
              compat(app, num);
        end

        % Callback function
        function Button_34Pushed(app, event)
            num = 34; 
              compat(app, num);
        end

        % Callback function
        function Button_35Pushed(app, event)
            num = 35; 
              compat(app, num);
        end

        % Callback function
        function Button_36Pushed(app, event)
            num = 36; 
              compat(app, num);
        end

        % Callback function
        function Button_37Pushed(app, event)
            num = 37; 
              compat(app, num);
        end

        % Callback function
        function Button_38Pushed(app, event)
            num = 38; 
              compat(app, num);
        end

        % Callback function
        function Button_39Pushed(app, event)
            num = 39; 
              compat(app, num);
        end

        % Callback function
        function Button_40Pushed(app, event)
            num = 40; 
              compat(app, num);
        end

        % Callback function
        function Button_41Pushed(app, event)
            num = 41; 
              compat(app, num);
        end

        % Callback function
        function Button_42Pushed(app, event)
            num = 42; 
              compat(app, num);
        end

        % Callback function
        function Button_43Pushed(app, event)
            num = 43; 
              compat(app, num);
        end

        % Callback function
        function Button_44Pushed(app, event)
            num = 44; 
              compat(app, num);
        end

        % Callback function
        function Button_45Pushed(app, event)
            num = 45; 
              compat(app, num);
        end

        % Callback function
        function Button_46Pushed(app, event)
            num = 46; 
              compat(app, num);
        end

        % Callback function
        function Button_47Pushed(app, event)
            num = 47; 
              compat(app, num);
        end

        % Callback function
        function Button_48Pushed(app, event)
            num = 48; 
              compat(app, num);
        end

        % Callback function
        function Button_49Pushed(app, event)
            num = 49; 
              compat(app, num);
        end

        % Callback function
        function Button_50Pushed(app, event)
            num = 50; 
              compat(app, num);
        end

        % Callback function
        function Button_51Pushed(app, event)
            num = 51; 
              compat(app, num);
        end

        % Callback function
        function Button_52Pushed(app, event)
            num = 52; 
              compat(app, num);
        end

        % Button pushed function: CheckWinButton
        function CheckWinButtonPushed(app, event)
            if (sum([app.playerHand > 0]) == 0)
    
        app.Label.Text = "You Win!";
        app.Label2.Text = "You Win!";
            else 
app.Label.Text = "Not quite.";
        app.Label2.Text = "Not quite.";
end
        end

        % Button pushed function: Player1
        function Player1Pressed(app, event)
            app.playerCount = [ 1 0 0 ];
            app.Player1Label.Text = "Loading";
            app.writePlayerCount();
            app.Player1Label.Text = "Ready";
            app.playerNum = 1;
        end

        % Button pushed function: Player2
        function Player2Pressed(app, event)
            if(app.Player1Label.Text == "Ready")
                app.playerCount = [ 1 1 0 ];
                app.Player2Label.Text = "Loading";
                app.writePlayerCount();
                app.Player2Label.Text = "Ready";
                app.playerNum = 2;
            else
                app.Player2Label.Text = "Player 1 has not Joined";
            end
        end

        % Button pushed function: Player3
        function Player3Pressed(app, event)
            if(app.Player2Label.Text == "Ready")
                app.playerCount = [ 1 1 1];
                app.Player3Label.Text = "Loading";
                app.writePlayerCount();
                app.Player3Label.Text = "Ready";
                app.playerNum = 3;
            else
                app.Player2Label.Text = "Player 2 has not Joined";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.NumberofPlayersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPlayersEditFieldLabel.Text = 'Number of Players';
            app.NumberofPlayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPlayersEditField.Limits = [0 4];
            app.NumberofPlayersEditField.Value = 1;
            app.Spot1Label = uilabel(app.UIFigure);
            app.Spot1Label.Text = 'Spot 1';
            app.Spot10Label = uilabel(app.UIFigure);
            app.Spot10Label.Text = 'Spot 10';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.CurrentCardLabel = uilabel(app.UIFigure);
            app.CurrentCardLabel.Text = 'Current Card: ';
            app.HintsLabel = uilabel(app.UIFigure);
            app.HintsLabel.Text = 'Hints:';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.SuitSelectLabel = uilabel(app.UIFigure);
            app.SuitSelectLabel.Text = 'Suit Select:';
            app.ClubButton = uibutton(app.UIFigure, 'push');
            app.ClubButton.ButtonPushedFcn = createCallbackFcn(app, @ClubButtonPushed, true);
            app.ClubButton.Text = 'Club';
            app.SpadeButton = uibutton(app.UIFigure, 'push');
            app.SpadeButton.ButtonPushedFcn = createCallbackFcn(app, @SpadeButtonPushed, true);
            app.SpadeButton.Text = 'Spade';
            app.HeartButton = uibutton(app.UIFigure, 'push');
            app.HeartButton.ButtonPushedFcn = createCallbackFcn(app, @HeartButtonPushed, true);
            app.HeartButton.Text = 'Heart';
            app.DiamondButton = uibutton(app.UIFigure, 'push');
            app.DiamondButton.ButtonPushedFcn = createCallbackFcn(app, @DiamondButtonPushed, true);
            app.DiamondButton.Text = 'Diamond';
            app.CheckWinButton = uibutton(app.UIFigure, 'push');
            app.CheckWinButton.ButtonPushedFcn = createCallbackFcn(app, @CheckWinButtonPushed, true);
            app.CheckWinButton.Text = 'Check Win';
            app.Image1 = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Image10 = uiimage(app.UIFigure);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Image11 = uiimage(app.UIFigure);
            app.Image11.ImageClickedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Image12 = uiimage(app.UIFigure);
            app.Image12.ImageClickedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Image13 = uiimage(app.UIFigure);
            app.Image13.ImageClickedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Image14 = uiimage(app.UIFigure);
            app.Image14.ImageClickedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Image15 = uiimage(app.UIFigure);
            app.Image15.ImageClickedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Image16 = uiimage(app.UIFigure);
            app.Image16.ImageClickedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Image17 = uiimage(app.UIFigure);
            app.Image17.ImageClickedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Image18 = uiimage(app.UIFigure);
            app.Image18.ImageClickedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Image19 = uiimage(app.UIFigure);
            app.Image19.ImageClickedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Image20 = uiimage(app.UIFigure);
            app.Image20.ImageClickedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Image21 = uiimage(app.UIFigure);
            app.Image21.ImageClickedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Player1 = uibutton(app.UIFigure, 'push');
            app.Player1.ButtonPushedFcn = createCallbackFcn(app, @Player1Pressed, true);
            app.Player1.Text = 'Join as Player1';
            app.Player2 = uibutton(app.UIFigure, 'push');
            app.Player2.ButtonPushedFcn = createCallbackFcn(app, @Player2Pressed, true);
            app.Player2.Text = 'Join as Player2';
            app.Player3 = uibutton(app.UIFigure, 'push');
            app.Player3.ButtonPushedFcn = createCallbackFcn(app, @Player3Pressed, true);
            app.Player3.Text = 'Join as Player3';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Unready';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Unready';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Unready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crazy8image_latest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end